classdef GenerateHeader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HeaderResultTextArea       matlab.ui.control.TextArea
        HeaderResultTextAreaLabel  matlab.ui.control.Label
        GenerateHeaderButton       matlab.ui.control.Button
        FileNameEditField          matlab.ui.control.EditField
        FileNameEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileName % filename input from edit field
        HeaderText % header text generate from generateHeader function
    end
    
    methods (Access = private)
        
        function header = generateHeader(~,input)
                % Constants
                programmerName = 'YHCHOW';
                matlabVersion = version;
            
                % Get the current date
                dateCreated = datestr(now, 'yyyy-mm-dd');
            
                % Define the header contents
                headerContents = {
                    ['FileName: ', input]
                    ['MATLAB Version: ', matlabVersion]
                    ['Date Created: ', dateCreated]
                    ['Programmer: ', programmerName]
                };
            
                % Determine the length of the longest line
                maxLength = max(cellfun(@length, headerContents));
            
                % Create the header with maximum space before %
                header = '';
                for i = 1:length(headerContents)
                    content = headerContents{i};
                    padding = repmat(' ', 1, maxLength - length(content) + 11); % 11 is the extra padding for maximum space
                    header = sprintf('%s%% %s%s%%\n%% %s %%\n', header, content, padding, repmat(' ', 1, maxLength + 10)); % Add each line and a blank line with %
                end
            
                % Add the underline
                underline = repmat('_', 1, maxLength + 10); % Adjust the underline length to match the content lines
                header = sprintf('%s%% %s %%\n', header, underline);          
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            app.FileName =value;
            
        end

        % Button pushed function: GenerateHeaderButton
        function GenerateHeaderButtonPushed(app, event)
            app.HeaderText = generateHeader(app,app.FileName);
            app.HeaderResultTextArea.Value = app.HeaderText;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.GenerateHeaderButton = uibutton(app.UIFigure, 'push');
            app.GenerateHeaderButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateHeaderButtonPushed, true);
            app.GenerateHeaderButton.Text = 'Generate Header!';
            app.HeaderResultTextAreaLabel = uilabel(app.UIFigure);
            app.HeaderResultTextAreaLabel.Text = 'Header Result';
            app.HeaderResultTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateHeader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end